classdef SynchrophasorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        status_window_output    matlab.ui.control.CheckBox
        command_window_output   matlab.ui.control.CheckBox
        session_report          matlab.ui.control.CheckBox
        LabelDropDown           matlab.ui.control.Label
        Transport_Protocol      matlab.ui.control.DropDown
        Label2                  matlab.ui.control.Label
        LabelEditField2         matlab.ui.control.Label
        PMU_IP_Address          matlab.ui.control.EditField
        LabelNumericEditField   matlab.ui.control.Label
        PMU_port_number         matlab.ui.control.NumericEditField
        Label3                  matlab.ui.control.Label
        LabelNumericEditField2  matlab.ui.control.Label
        SimulationTime          matlab.ui.control.NumericEditField
        LabelNumericEditField3  matlab.ui.control.Label
        PMU_ID_value            matlab.ui.control.NumericEditField
        LabelDropDown2          matlab.ui.control.Label
        C37118_Protocol         matlab.ui.control.DropDown
        Tab2                    matlab.ui.container.Tab
        TabGroup2               matlab.ui.container.TabGroup
        Tab3                    matlab.ui.container.Tab
        Aone_four               matlab.ui.control.CheckBox
        Atwo_seven              matlab.ui.control.CheckBox
        Athree_nine             matlab.ui.control.CheckBox
        Afour_five              matlab.ui.control.CheckBox
        Afour_six               matlab.ui.control.CheckBox
        Aseven_five             matlab.ui.control.CheckBox
        Aseven_eight            matlab.ui.control.CheckBox
        Anine_six               matlab.ui.control.CheckBox
        Anine_eight             matlab.ui.control.CheckBox
        Label4                  matlab.ui.control.Label
        Label5                  matlab.ui.control.Label
        Label6                  matlab.ui.control.Label
        Tab4                    matlab.ui.container.Tab
        Rone_four               matlab.ui.control.CheckBox
        Rtwo_seven              matlab.ui.control.CheckBox
        Rthree_nine             matlab.ui.control.CheckBox
        Rfour_five              matlab.ui.control.CheckBox
        Rfour_six               matlab.ui.control.CheckBox
        Rseven_five             matlab.ui.control.CheckBox
        Rseven_eight            matlab.ui.control.CheckBox
        Rnine_six               matlab.ui.control.CheckBox
        Rnine_eight             matlab.ui.control.CheckBox
        Label7                  matlab.ui.control.Label
        Label8                  matlab.ui.control.Label
        Button                  matlab.ui.control.Button
        Label9                  matlab.ui.control.Label
        Button2                 matlab.ui.control.Button
        Label10                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        com_mode = true;
        protocol = 'udp';
        conf_fr_ver = 2;
        ip_address_primary = '0.0.0.0';
        port_primary = 1111;
        device_id = 1;
        verbose_info = false;
        verbose_debug = false;
        session_report1 = false;
        sim_time = 100;
        Active = [];
        Reactive = [];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all
            app.com_mode = true;
        end

        % Value changed function: Transport_Protocol
        function Transport_ProtocolValueChanged(app, event)
            value = app.Transport_Protocol.Value;
            app.protocol = value;
        end

        % Value changed function: C37118_Protocol
        function C37118_ProtocolValueChanged(app, event)
            value = app.C37118_Protocol.Value;
            if strcmp(value,'C37.118 - 2005')
                app.conf_fr_ver = 2;
            else
                app.conf_fr_ver = 3;
            end
        end

        % Value changed function: PMU_IP_Address
        function PMU_IP_AddressValueChanged(app, event)
            value = app.PMU_IP_Address.Value;
            app.ip_address_primary = value;
        end

        % Value changed function: PMU_port_number
        function PMU_port_numberValueChanged(app, event)
            value = app.PMU_port_number.Value;
            app.port_primary = value;
        end

        % Value changed function: PMU_ID_value
        function PMU_ID_valueValueChanged(app, event)
            value = app.PMU_ID_value.Value;
            app.device_id = value;
        end

        % Value changed function: status_window_output
        function status_window_outputValueChanged(app, event)
            value = app.status_window_output.Value;
            app.verbose_info = value;
        end

        % Value changed function: command_window_output
        function command_window_outputValueChanged(app, event)
            value = app.command_window_output.Value;
            app.verbose_debug = value;
        end

        % Value changed function: session_report
        function session_reportValueChanged(app, event)
            value = app.session_report.Value;
            app.session_report1 = value;
        end

        % Value changed function: SimulationTime
        function SimulationTimeValueChanged(app, event)
            value = app.SimulationTime.Value;
            app.sim_time = value;
        end

        % Callback function
        function ButtonButtonPushed(app, event)
            
            Com_mode = app.com_mode;
            Protocol = app.protocol;
            Conf_fr_ver = app.conf_fr_ver;
            Ip_address_primary = app.ip_address_primary;
            Port_primary = app.port_primary;
            Device_id = app.device_id;
            Verbose_info = app.verbose_info;
            Verbose_debug = app.verbose_debug;
            Session_report1 = app.session_report1;
            Sim_time = app.sim_time;
            ActivePower1 = app.Active;
            ReactivePower1 = app.Reactive;
            
            save('TEMPSADF.mat','Com_mode','Protocol','Conf_fr_ver','Ip_address_primary','Port_primary','Device_id','Verbose_info','Verbose_debug','Session_report1','Sim_time','ActivePower1','ReactivePower1');
            
            SADF_run();
            app.Active = [];
            app.Reactive = [];
        end

        % Value changed function: Aone_four
        function Aone_fourValueChanged(app, event)
            value = app.Aone_four.Value;
            if(value==true)
                app.Active = [app.Active 14];
            else
                app.Active(app.Active==14) = [];
            end
        end

        % Value changed function: Atwo_seven
        function Atwo_sevenValueChanged(app, event)
            value = app.Atwo_seven.Value;
            if(value==true)
                app.Active = [app.Active 27];
            else
                app.Active(app.Active==27) = [];
            end
        end

        % Value changed function: Athree_nine
        function Athree_nineValueChanged(app, event)
            value = app.Athree_nine.Value;
            if(value==true)
                app.Active = [app.Active 39];
            else
                app.Active(app.Active==39) = [];
            end
        end

        % Value changed function: Afour_five
        function Afour_fiveValueChanged(app, event)
            value = app.Afour_five.Value;
            if(value==true)
                app.Active = [app.Active 45];
            else
                app.Active(app.Active==45) = [];
            end
        end

        % Value changed function: Afour_six
        function Afour_sixValueChanged(app, event)
            value = app.Afour_six.Value;
            if(value==true)
                app.Active = [app.Active 46];
            else
                app.Active(app.Active==46) = [];
            end
        end

        % Value changed function: Aseven_five
        function Aseven_fiveValueChanged(app, event)
            value = app.Aseven_five.Value;
            if(value==true)
                app.Active = [app.Active 75];
            else
                app.Active(app.Active==75) = [];
            end
        end

        % Value changed function: Aseven_eight
        function Aseven_eightValueChanged(app, event)
            value = app.Aseven_eight.Value;
            if(value==true)
                app.Active = [app.Active 78];
            else
                app.Active(app.Active==78) = [];
            end
        end

        % Value changed function: Anine_six
        function Anine_sixValueChanged(app, event)
            value = app.Anine_six.Value;
            if(value==true)
                app.Active = [app.Active 96];
            else
                app.Active(app.Active==98) = [];
            end
        end

        % Value changed function: Anine_eight
        function Anine_eightValueChanged(app, event)
            value = app.Anine_eight.Value;
            if(value==true)
                app.Active = [app.Active 98];
            else
                app.Active(app.Active==98) = [];
            end
        end

        % Value changed function: Rone_four
        function Rone_fourValueChanged(app, event)
            value = app.Rone_four.Value;
            if(value==true)
                app.Reactive = [app.Reactive 14];
            else
                app.Reactive(app.Reactive==14) = [];
            end
        end

        % Value changed function: Rtwo_seven
        function Rtwo_sevenValueChanged(app, event)
            value = app.Rtwo_seven.Value;
            if(value==true)
                app.Reactive = [app.Reactive 27];
            else
                app.Reactive(app.Reactive==27) = [];
            end
        end

        % Value changed function: Rthree_nine
        function Rthree_nineValueChanged(app, event)
            value = app.Rthree_nine.Value;
            if(value==true)
                app.Reactive = [app.Reactive 39];
            else
                app.Reactive(app.Reactive==39) = [];
            end
        end

        % Value changed function: Rfour_five
        function Rfour_fiveValueChanged(app, event)
            value = app.Rfour_five.Value;
            if(value==true)
                app.Reactive = [app.Reactive 45];
            else
                app.Reactive(app.Reactive==45) = [];
            end
        end

        % Value changed function: Rfour_six
        function Rfour_sixValueChanged(app, event)
            value = app.Rfour_six.Value;
            if(value==true)
                app.Reactive = [app.Reactive 46];
            else
                app.Reactive(app.Reactive==46) = [];
            end
        end

        % Value changed function: Rseven_five
        function Rseven_fiveValueChanged(app, event)
            value = app.Rseven_five.Value;
            if(value==true)
                app.Reactive = [app.Reactive 75];
            else
                app.Reactive(app.Reactive==75) = [];
            end
        end

        % Value changed function: Rseven_eight
        function Rseven_eightValueChanged(app, event)
            value = app.Rseven_eight.Value;
            if(value==true)
                app.Reactive = [app.Reactive 78];
            else
                app.Reactive(app.Reactive==78) = [];
            end
        end

        % Value changed function: Rnine_six
        function Rnine_sixValueChanged(app, event)
            value = app.Rnine_six.Value;
            if(value==true)
                app.Reactive = [app.Reactive 96];
            else
                app.Reactive(app.Reactive==96) = [];
            end
        end

        % Value changed function: Rnine_eight
        function Rnine_eightValueChanged(app, event)
            value = app.Rnine_eight.Value;
            if(value==true)
                app.Reactive = [app.Reactive 98];
            else
                app.Reactive(app.Reactive==98) = [];
            end
        end

        % Button pushed function: Button
        function StartRealTime(app, event)
                        
            Com_mode = app.com_mode;
            Protocol = app.protocol;
            Conf_fr_ver = app.conf_fr_ver;
            Ip_address_primary = app.ip_address_primary;
            Port_primary = app.port_primary;
            Device_id = app.device_id;
            Verbose_info = app.verbose_info;
            Verbose_debug = app.verbose_debug;
            Session_report1 = app.session_report1;
            Sim_time = app.sim_time;
            ActivePower1 = app.Active;
            ReactivePower1 = app.Reactive;
            
            save('TEMPSADF.mat','Com_mode','Protocol','Conf_fr_ver','Ip_address_primary','Port_primary','Device_id','Verbose_info','Verbose_debug','Session_report1','Sim_time','ActivePower1','ReactivePower1');
            
            SADF_run();
            app.Active = [];
            app.Reactive = [];
        end

        % Button pushed function: Button2
        function plotComplete(app, event)
            PlotCompletedata();
        end

        % Size changed function: Tab2
        function Tab2SizeChanged(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Settings';
            app.status_window_output = uicheckbox(app.Tab);
            app.status_window_output.ValueChangedFcn = createCallbackFcn(app, @status_window_outputValueChanged, true);
            app.status_window_output.Text = 'Show status window output (SADF)';
            app.command_window_output = uicheckbox(app.Tab);
            app.command_window_output.ValueChangedFcn = createCallbackFcn(app, @command_window_outputValueChanged, true);
            app.command_window_output.Text = 'Show command window output (PMU Messages)';
            app.session_report = uicheckbox(app.Tab);
            app.session_report.ValueChangedFcn = createCallbackFcn(app, @session_reportValueChanged, true);
            app.session_report.Text = 'Print SADF session report';
            app.LabelDropDown = uilabel(app.Tab);
            app.LabelDropDown.Text = 'Transport Protocol';
            app.Transport_Protocol = uidropdown(app.Tab);
            app.Transport_Protocol.Items = {'-select-', 'tcp', 'udp'};
            app.Transport_Protocol.ValueChangedFcn = createCallbackFcn(app, @Transport_ProtocolValueChanged, true);
            app.Transport_Protocol.Value = '-select-';
            app.Label2 = uilabel(app.Tab);
            app.Label2.Text = 'PMU/PDC Connection Settings';
            app.LabelEditField2 = uilabel(app.Tab);
            app.LabelEditField2.Text = 'PMU/PDC IP Address';
            app.PMU_IP_Address = uieditfield(app.Tab, 'text');
            app.PMU_IP_Address.ValueChangedFcn = createCallbackFcn(app, @PMU_IP_AddressValueChanged, true);
            app.LabelNumericEditField = uilabel(app.Tab);
            app.LabelNumericEditField.Text = 'PMU/PDC port number';
            app.PMU_port_number = uieditfield(app.Tab, 'numeric');
            app.PMU_port_number.Limits = [0 9999];
            app.PMU_port_number.ValueChangedFcn = createCallbackFcn(app, @PMU_port_numberValueChanged, true);
            app.Label3 = uilabel(app.Tab);
            app.Label3.Text = 'Simulation Settings';
            app.LabelNumericEditField2 = uilabel(app.Tab);
            app.LabelNumericEditField2.Text = 'Max. Simulation Time (seconds)';
            app.SimulationTime = uieditfield(app.Tab, 'numeric');
            app.SimulationTime.Limits = [0 Inf];
            app.SimulationTime.ValueChangedFcn = createCallbackFcn(app, @SimulationTimeValueChanged, true);
            app.LabelNumericEditField3 = uilabel(app.Tab);
            app.LabelNumericEditField3.Text = 'PMU/PDC ID value';
            app.PMU_ID_value = uieditfield(app.Tab, 'numeric');
            app.PMU_ID_value.Limits = [0 Inf];
            app.PMU_ID_value.ValueChangedFcn = createCallbackFcn(app, @PMU_ID_valueValueChanged, true);
            app.LabelDropDown2 = uilabel(app.Tab);
            app.LabelDropDown2.Text = 'C37.118 Protocol';
            app.C37118_Protocol = uidropdown(app.Tab);
            app.C37118_Protocol.Items = {'-select-', 'C37.118 - 2005', 'C37.118 - 2011'};
            app.C37118_Protocol.ValueChangedFcn = createCallbackFcn(app, @C37118_ProtocolValueChanged, true);
            app.C37118_Protocol.Value = '-select-';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.SizeChangedFcn = createCallbackFcn(app, @Tab2SizeChanged, true);
            app.Tab2.Title = 'Power Calculations';
            app.TabGroup2 = uitabgroup(app.Tab2);
            app.Tab3 = uitab(app.TabGroup2);
            app.Tab3.Title = 'Active Power';
            app.Aone_four = uicheckbox(app.Tab3);
            app.Aone_four.ValueChangedFcn = createCallbackFcn(app, @Aone_fourValueChanged, true);
            app.Aone_four.Text = 'Branch 1 to 4';
            app.Atwo_seven = uicheckbox(app.Tab3);
            app.Atwo_seven.ValueChangedFcn = createCallbackFcn(app, @Atwo_sevenValueChanged, true);
            app.Atwo_seven.Text = 'Branch 2 to 7';
            app.Athree_nine = uicheckbox(app.Tab3);
            app.Athree_nine.ValueChangedFcn = createCallbackFcn(app, @Athree_nineValueChanged, true);
            app.Athree_nine.Text = 'Branch 3 to 9';
            app.Afour_five = uicheckbox(app.Tab3);
            app.Afour_five.ValueChangedFcn = createCallbackFcn(app, @Afour_fiveValueChanged, true);
            app.Afour_five.Text = 'Branch 4 to 5';
            app.Afour_six = uicheckbox(app.Tab3);
            app.Afour_six.ValueChangedFcn = createCallbackFcn(app, @Afour_sixValueChanged, true);
            app.Afour_six.Text = 'Branch 4 to 6';
            app.Aseven_five = uicheckbox(app.Tab3);
            app.Aseven_five.ValueChangedFcn = createCallbackFcn(app, @Aseven_fiveValueChanged, true);
            app.Aseven_five.Text = 'Branch 7 to 5';
            app.Aseven_eight = uicheckbox(app.Tab3);
            app.Aseven_eight.ValueChangedFcn = createCallbackFcn(app, @Aseven_eightValueChanged, true);
            app.Aseven_eight.Text = 'Branch 7 to 8';
            app.Anine_six = uicheckbox(app.Tab3);
            app.Anine_six.ValueChangedFcn = createCallbackFcn(app, @Anine_sixValueChanged, true);
            app.Anine_six.Text = 'Branch 9 to 6';
            app.Anine_eight = uicheckbox(app.Tab3);
            app.Anine_eight.ValueChangedFcn = createCallbackFcn(app, @Anine_eightValueChanged, true);
            app.Anine_eight.Text = 'Branch 9 to 8';
            app.Label4 = uilabel(app.Tab3);
            app.Label4.Text = '';
            app.Label5 = uilabel(app.Tab3);
            app.Label5.Text = 'Note: Select at max two options ';
            app.Label6 = uilabel(app.Tab3);
            app.Label6.Text = 'for better viewing and speed.';
            app.Tab4 = uitab(app.TabGroup2);
            app.Tab4.Title = 'Reactive Power';
            app.Rone_four = uicheckbox(app.Tab4);
            app.Rone_four.ValueChangedFcn = createCallbackFcn(app, @Rone_fourValueChanged, true);
            app.Rone_four.Text = 'Branch 1 to 4';
            app.Rtwo_seven = uicheckbox(app.Tab4);
            app.Rtwo_seven.ValueChangedFcn = createCallbackFcn(app, @Rtwo_sevenValueChanged, true);
            app.Rtwo_seven.Text = 'Branch 2 to 7';
            app.Rthree_nine = uicheckbox(app.Tab4);
            app.Rthree_nine.ValueChangedFcn = createCallbackFcn(app, @Rthree_nineValueChanged, true);
            app.Rthree_nine.Text = 'Branch 3 to 9';
            app.Rfour_five = uicheckbox(app.Tab4);
            app.Rfour_five.ValueChangedFcn = createCallbackFcn(app, @Rfour_fiveValueChanged, true);
            app.Rfour_five.Text = 'Branch 4 to 5';
            app.Rfour_six = uicheckbox(app.Tab4);
            app.Rfour_six.ValueChangedFcn = createCallbackFcn(app, @Rfour_sixValueChanged, true);
            app.Rfour_six.Text = 'Branch 4 to 6';
            app.Rseven_five = uicheckbox(app.Tab4);
            app.Rseven_five.ValueChangedFcn = createCallbackFcn(app, @Rseven_fiveValueChanged, true);
            app.Rseven_five.Text = 'Branch 7 to 5';
            app.Rseven_eight = uicheckbox(app.Tab4);
            app.Rseven_eight.ValueChangedFcn = createCallbackFcn(app, @Rseven_eightValueChanged, true);
            app.Rseven_eight.Text = 'Branch 7 to 8';
            app.Rnine_six = uicheckbox(app.Tab4);
            app.Rnine_six.ValueChangedFcn = createCallbackFcn(app, @Rnine_sixValueChanged, true);
            app.Rnine_six.Text = 'Branch 9 to 6';
            app.Rnine_eight = uicheckbox(app.Tab4);
            app.Rnine_eight.ValueChangedFcn = createCallbackFcn(app, @Rnine_eightValueChanged, true);
            app.Rnine_eight.Text = 'Branch 9 to 8';
            app.Label7 = uilabel(app.Tab4);
            app.Label7.Text = 'Note: Select at max two options ';
            app.Label8 = uilabel(app.Tab4);
            app.Label8.Text = 'for better viewing and speed.';
            app.Button = uibutton(app.Tab2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @StartRealTime, true);
            app.Button.Text = 'Start';
            app.Label9 = uilabel(app.Tab2);
            app.Label9.Text = '(Real Time Plot)';
            app.Button2 = uibutton(app.Tab2, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @plotComplete, true);
            app.Button2.Text = 'Plot';
            app.Label10 = uilabel(app.Tab2);
            app.Label10.Text = '(Complete response plot)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'SYNCHROPHASOR APPLICATION TOOL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SynchrophasorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end